classdef HEKA_Explorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainFIgure                   matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        OptionsPanel                 matlab.ui.container.Panel
        AppTitle                     matlab.ui.control.Label
        OptionsGroup                 matlab.ui.container.TabGroup
        DataOptionsTab               matlab.ui.container.Tab
        DataLoadingOptionsPanel      matlab.ui.container.Panel
        LoadDataButton               matlab.ui.control.Button
        DataFormatButtonGroup        matlab.ui.container.ButtonGroup
        LoadDATPatchMasterButton     matlab.ui.control.RadioButton
        LoadMATDataTableButton       matlab.ui.control.RadioButton
        LoadITXPatchMasterButton     matlab.ui.control.RadioButton
        LoadMATPatchMasterButton     matlab.ui.control.RadioButton
        LoadMATITXButton             matlab.ui.control.RadioButton
        DataConverstionPanel         matlab.ui.container.Panel
        DataLog                      matlab.ui.control.TextArea
        ConvertITXtoMATButton        matlab.ui.control.Button
        ConvertDATtoMATButton        matlab.ui.control.Button
        ViewOptionsTab               matlab.ui.container.Tab
        ViewProtocolAndSweepPanel    matlab.ui.container.Panel
        ProtocolDropDownLabel        matlab.ui.control.Label
        ViewProtocolSelection        matlab.ui.control.DropDown
        SweepEditFieldLabel          matlab.ui.control.Label
        ViewSweepSelection           matlab.ui.control.NumericEditField
        ViewProtocolNameChanger      matlab.ui.control.Button
        ViewPreviousProtocol         matlab.ui.control.Button
        ViewNextProtocol             matlab.ui.control.Button
        ViewAllSweep                 matlab.ui.control.CheckBox
        VIewPreviousSweep            matlab.ui.control.Button
        ViewNextSweep                matlab.ui.control.Button
        ViewPlotPropertiesPanel      matlab.ui.container.Panel
        NunberDropDownLabel          matlab.ui.control.Label
        ViewPlotNumber               matlab.ui.control.DropDown
        HeightratioEditFieldLabel    matlab.ui.control.Label
        ViewPlotHeighRatio           matlab.ui.control.NumericEditField
        ViewVariableSelectionPanel   matlab.ui.container.Panel
        ViewVariableGrid             matlab.ui.container.GridLayout
        ViewVariable_1               matlab.ui.control.DropDown
        ViewVariable_2               matlab.ui.control.DropDown
        ViewVariable_3               matlab.ui.control.DropDown
        ViewVariable_4               matlab.ui.control.DropDown
        ViewVariable_5               matlab.ui.control.DropDown
        ViewVariable_6               matlab.ui.control.DropDown
        ViewVariable_7               matlab.ui.control.DropDown
        ViewVariable_8               matlab.ui.control.DropDown
        ViewVariable_9               matlab.ui.control.DropDown
        ViewVariablePropertiesPanel  matlab.ui.container.Panel
        ViewVariablePropertiesGridTemplate  matlab.ui.container.GridLayout
        ViewXAxispropertiesPanel     matlab.ui.container.Panel
        VIewShowAllXAxis             matlab.ui.control.StateButton
        XLimEditFieldLabel           matlab.ui.control.Label
        ViewXLim                     matlab.ui.control.NumericEditField
        ViewSaveOptionsPanel         matlab.ui.container.Panel
        ViewSaveSelectedOnly         matlab.ui.control.CheckBox
        ViewSaveDataFormat           matlab.ui.container.ButtonGroup
        ViewSaveMatButton            matlab.ui.control.RadioButton
        ViewLog                      matlab.ui.control.TextArea
        ViewSaveCSVButton            matlab.ui.control.RadioButton
        AnalysisLog                  matlab.ui.control.TextArea
        ViewSaveDataButton           matlab.ui.control.Button
        ViewScreenshotButton         matlab.ui.control.Button
        AnalysisOptionsTab           matlab.ui.container.Tab
        OutputsGroup                 matlab.ui.container.TabGroup
        ViewOutputsTab               matlab.ui.container.Tab
        ViewFigureBoundary           matlab.ui.container.Panel
        AnalysisOutputsTab           matlab.ui.container.Tab
        AnalysisFigureBoundary       matlab.ui.container.Panel
        DataInformation              matlab.ui.control.EditField
        OutputsPanel                 matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = public)
        % TODO: turn these into PRIVATE after development
        view_max_numplots = 9;          % maximum number of allowed plots in View
        current_data = struct;          % currently plotted data
        current_settings = struct;      % current settings/options
        current_variables = struct;     % current variables
    end
    
    properties (Access = public)
        % TODO: decide on whether this should be publicly accessible or
        % turned to private
        data_table;                     % experiment data table
    end
    
    methods (Access = private)
        
        function [file_format, file_source] = getLoadDataFormat(app)
            selected_format = app.DataFormatButtonGroup.SelectedObject.Text;
            
            switch upper(selected_format)
                case upper('dat (from PatchMaster)')
                    file_format = 'dat';
                    file_source = 'PATCHMASTER::DAT';
                case upper('mat (data table)')
                    file_format = 'mat';
                    file_source = 'PATCHMASTER::DAT->HEKAEXPLORER::MAT';
                case upper('itx (from PatchMaster)')
                    file_format = 'itx';
                    file_source = 'PATCHMASTER::ITX';
                case upper('mat (from PatchMaster)')
                    file_format = 'mat';
                    file_source = 'PATCHMASTER::MAT';
                case upper('mat (from itx)')
                    file_format = 'mat';
                    file_source = 'PATCHMASTER::ITX->HEKAEXPLORER::MAT';
                otherwise
                    app.updateLog('DataLog', 'WARNING', ...
                        sprintf('%s is not an acceptable data format option. Nothing will be loaded.', selected_format));
                    file_format = 'INVALID';
                    file_source = 'INVALID';
            end
        end
        
        function updateLog(app, componentName, tag, newMessage)
            tag = upper(tag); 
            app.(componentName).Value = [app.(componentName).Value; {sprintf('%s:\t%s', tag, newMessage)}];
            switch tag 
                case {'WARNING', 'WARN'}
                    uialert(app.MainFIgure, newMessage, 'Icon', 'warning'); 
                case {'ERROR', 'ERR'}
                    uialert(app.MainFIgure, newMessage, 'Icon', 'error'); 
                otherwise, return; 
            end
        end
        
        function initializeAfterLoadingData(app)
            
            selected_protocol_value = 1;
            app.current_settings.available_protocols = cellfun(@(ind_rec, stim_name) ...
                sprintf('%02d: %s',ind_rec,stim_name), ...
                num2cell(app.data_table.Rec), app.data_table.Stimulus, ...
                'uni', 0);
            app.current_settings.original_available_protocols = app.current_settings.available_protocols;
            app.ProtocolSelectionMenu.String = app.current_settings.available_protocols;
            app.ProtocolSelectionMenu.Value = selected_protocol_value;
            
            app.NumberOfVariableMenu.String = 1:app.max_numplots;
            app.ProtocolSelectionMenuChanged;
            
        end
        
        function switchToViewTabs(app)            
            app.OptionsGroup.SelectedTab = app.ViewOptionsTab;
            app.OutputsGroup.SelectedTab = app.ViewOutputsTab; 
        end
        
        function switchToAnalysTabs(app)            
            app.OptionsGroup.SelectedTab = app.AnalysisOptionsTab;
            app.OutputsGroup.SelectedTab = app.AnalysisOutputsTab; 
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            % Prompt get file
            if ~isfield(app.current_settings, 'file_path'), app.current_settings.file_path = pwd; end
            file_path = app.current_settings.file_path;
            
            [file_format, file_source] = app.getLoadDataFormat;
            [file_name, file_path] = uigetfile(fullfile(file_path,['*.' file_format]), ...
                sprintf('Select a %s file to read', upper(file_format)));
            
            if file_name == 0, return; end
            
            app.current_settings.file_path = file_path;
            app.current_settings.file_name = file_name;
            
            % Update log
            app.updateLog('DataLog', 'DATA', 'wait for it ...');
            pause(0.001);
            
            % Obtain data table
            full_filename = fullfile(file_path, file_name);
            switch file_source
                case 'PATCHMASTER::DAT'
                    DataAlreadyZeroed_inputoptions = {'I know they ARE',...
                        'I know they are NOT', ...
                        'Unsure but let''s go with YES', ...
                        'Unsure but let''s go with NO'};
                    DataAlreadyZeroed_answer = listdlg('PromptString', ...
                        'Do you know if data have already been zeroed?', ...
                        'SelectionMode', 'single', ...
                        'ListString', DataAlreadyZeroed_inputoptions);
                    DataAlreadyZeroed_answer = DataAlreadyZeroed_answer == 1 || DataAlreadyZeroed_answer == 3;
                    
                    try
                        tmp_data = HEKA_Importer(full_filename, 'DataAlreadyZeroed', DataAlreadyZeroed_answer);
                    catch
                        app.updateLog('DataLog', 'ERROR', sprintf('HEKA_Importer could not load %s.', full_filename));
                    end
                    
                    if isempty(tmp_data.RecTable)
                        app.updateLog('DataLog', 'WARNING', 'The data table was empty. Nothing will be loaded in.');
                        return;
                    end
                    app.data_table = tmp_data.RecTable;
                    
                case 'PATCHMASTER::DAT->HEKAEXPLORER::MAT'
                    tmp_data = load(full_filename, 'data_table');
                    app.data_table = tmp_data.data_table;
                otherwise
                    app.updateLog('DataLog', 'WARNING', 'The file source option is either invalid or unimplemented for reading. Nothing will be loaded in.');
            end
            
            
            app.DataInformation.Value = file_name;
            app.updateLog('DataLog', 'DATA', sprintf('File "%s" loaded.', full_filename));
            app.updateLog('DataLog', 'TIPS', 'Now you can click on the other tabs to toy around.');
            
            % Initialize
            app.initializeAfterLoadingData;
            app.switchToViewTabs; 
            
        end

        % Button pushed function: ConvertDATtoMATButton
        function ConvertDATtoMATButtonPushed(app, event)
            
            if isempty(app.data_table) || isempty(app.current_settings.file_path)
                app.SaveMATTableText.String = 'no data to save';
                return;
            end
            
            [save_datatable_name, save_datatable_path] = uiputfile('.mat', ...
                'Select a home for the MAT data table file', ...
                sprintf('%s.mat', fullfile(app.current_settings.file_path, app.current_settings.file_name(1:end-4))));
            
            if save_datatable_name == 0, return; end
            save_filename = fullfile(save_datatable_path, save_datatable_name);
            
            data_table = app.data_table;
            save(save_filename, 'data_table');
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.MainFIgure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {866, 866};
                app.GridLayout.ColumnWidth = {'1x'};
                app.OutputsPanel.Layout.Row = 2;
                app.OutputsPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {370, '1x'};
                app.OutputsPanel.Layout.Row = 1;
                app.OutputsPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainFIgure = uifigure('Visible', 'off');
            app.MainFIgure.Name = 'UI Figure';
            app.MainFIgure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.MainFIgure);
            app.OptionsPanel = uipanel(app.GridLayout);
            app.AppTitle = uilabel(app.OptionsPanel);
            app.AppTitle.Text = 'PatchMaster recordings explorer';
            app.DataInformation = uieditfield(app.OptionsPanel, 'text');
            app.DataInformation.Enable = 'off';
            app.DataInformation.Value = '(no data loaded yet)';
            app.OptionsGroup = uitabgroup(app.OptionsPanel);
            app.DataOptionsTab = uitab(app.OptionsGroup);
            app.DataOptionsTab.Title = 'Data';
            app.DataLoadingOptionsPanel = uipanel(app.DataOptionsTab);
            app.LoadDataButton = uibutton(app.DataLoadingOptionsPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Tooltip = {'Click here and choose the file format to load the data'};
            app.LoadDataButton.Text = 'Load data';
            app.DataFormatButtonGroup = uibuttongroup(app.DataLoadingOptionsPanel);
            app.DataFormatButtonGroup.Tooltip = {'choose data format (the greyed ones are unimplmented)'; ''};
            app.DataFormatButtonGroup.Title = 'Data format';
            app.LoadDATPatchMasterButton = uiradiobutton(app.DataFormatButtonGroup);
            app.LoadDATPatchMasterButton.Tooltip = {'.dat files generated for the entire experiment from PatchMaster'};
            app.LoadDATPatchMasterButton.Text = 'dat (from PatchMaster)';
            app.LoadDATPatchMasterButton.Value = true;
            app.LoadMATDataTableButton = uiradiobutton(app.DataFormatButtonGroup);
            app.LoadMATDataTableButton.Tooltip = {'.mat files generated by this program that had the data table, extracted from the HEKA_Importer'};
            app.LoadMATDataTableButton.Text = 'mat (data table)';
            app.LoadITXPatchMasterButton = uiradiobutton(app.DataFormatButtonGroup);
            app.LoadITXPatchMasterButton.Enable = 'off';
            app.LoadITXPatchMasterButton.Tooltip = {'.itx files from PatchMaster for single protocol'; ''};
            app.LoadITXPatchMasterButton.Text = 'itx (from PatchMaster)';
            app.LoadMATPatchMasterButton = uiradiobutton(app.DataFormatButtonGroup);
            app.LoadMATPatchMasterButton.Enable = 'off';
            app.LoadMATPatchMasterButton.Tooltip = {'.mat files from PatchMaster for single protocol'};
            app.LoadMATPatchMasterButton.Text = 'mat (from PatchMaster)';
            app.LoadMATITXButton = uiradiobutton(app.DataFormatButtonGroup);
            app.LoadMATITXButton.Enable = 'off';
            app.LoadMATITXButton.Tooltip = {'.mat files converted from .itx files'};
            app.LoadMATITXButton.Text = 'mat (from itx)';
            app.DataConverstionPanel = uipanel(app.DataOptionsTab);
            app.DataConverstionPanel.Title = 'Conversion utilities';
            app.ConvertITXtoMATButton = uibutton(app.DataConverstionPanel, 'push');
            app.ConvertITXtoMATButton.Enable = 'off';
            app.ConvertITXtoMATButton.Tooltip = {'Convert the .itx files to.mat files for easy reading. Can do batch mode as well.'};
            app.ConvertITXtoMATButton.Text = 'Convert ITX to MAT';
            app.ConvertDATtoMATButton = uibutton(app.DataConverstionPanel, 'push');
            app.ConvertDATtoMATButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertDATtoMATButtonPushed, true);
            app.ConvertDATtoMATButton.Tooltip = {'Convert the PatchMaster .dat files to.mat files for easy reading (essentially from HEKA Importer). Can do batch mode as well.'};
            app.ConvertDATtoMATButton.Text = 'Convert DAT to MAT';
            app.DataLog = uitextarea(app.DataOptionsTab);
            app.DataLog.Enable = 'off';
            app.DataLog.Value = {'Log'};
            app.ViewOptionsTab = uitab(app.OptionsGroup);
            app.ViewOptionsTab.Title = 'View options';
            app.ViewProtocolAndSweepPanel = uipanel(app.ViewOptionsTab);
            app.ViewProtocolAndSweepPanel.Title = 'Protocol and sweep selection';
            app.ProtocolDropDownLabel = uilabel(app.ViewProtocolAndSweepPanel);
            app.ProtocolDropDownLabel.Enable = 'off';
            app.ProtocolDropDownLabel.Text = 'Protocol';
            app.ViewProtocolSelection = uidropdown(app.ViewProtocolAndSweepPanel);
            app.ViewProtocolSelection.Items = {};
            app.ViewProtocolSelection.Enable = 'off';
            app.ViewProtocolSelection.Tooltip = {'Select a protocol'};
            app.ViewProtocolSelection.Value = {};
            app.SweepEditFieldLabel = uilabel(app.ViewProtocolAndSweepPanel);
            app.SweepEditFieldLabel.Enable = 'off';
            app.SweepEditFieldLabel.Text = 'Sweep';
            app.ViewSweepSelection = uieditfield(app.ViewProtocolAndSweepPanel, 'numeric');
            app.ViewSweepSelection.Enable = 'off';
            app.ViewProtocolNameChanger = uibutton(app.ViewProtocolAndSweepPanel, 'push');
            app.ViewProtocolNameChanger.Enable = 'off';
            app.ViewProtocolNameChanger.Tooltip = {'Click here to change the name of the protocol'};
            app.ViewProtocolNameChanger.Text = '';
            app.ViewPreviousProtocol = uibutton(app.ViewProtocolAndSweepPanel, 'push');
            app.ViewPreviousProtocol.Enable = 'off';
            app.ViewPreviousProtocol.Tooltip = {'Previous protocol'};
            app.ViewPreviousProtocol.Text = '◀';
            app.ViewNextProtocol = uibutton(app.ViewProtocolAndSweepPanel, 'push');
            app.ViewNextProtocol.Enable = 'off';
            app.ViewNextProtocol.Tooltip = {'Next protocol'};
            app.ViewNextProtocol.Text = '▶';
            app.ViewAllSweep = uicheckbox(app.ViewProtocolAndSweepPanel);
            app.ViewAllSweep.Enable = 'off';
            app.ViewAllSweep.Tooltip = {'Click all to plot all weeps '};
            app.ViewAllSweep.Text = ' all';
            app.VIewPreviousSweep = uibutton(app.ViewProtocolAndSweepPanel, 'push');
            app.VIewPreviousSweep.Enable = 'off';
            app.VIewPreviousSweep.Tooltip = {'Previous protocol'};
            app.VIewPreviousSweep.Text = '◀';
            app.ViewNextSweep = uibutton(app.ViewProtocolAndSweepPanel, 'push');
            app.ViewNextSweep.Enable = 'off';
            app.ViewNextSweep.Tooltip = {'Next protocol'};
            app.ViewNextSweep.Text = '▶';
            app.ViewPlotPropertiesPanel = uipanel(app.ViewOptionsTab);
            app.ViewPlotPropertiesPanel.Title = 'Plot properties';
            app.NunberDropDownLabel = uilabel(app.ViewPlotPropertiesPanel);
            app.NunberDropDownLabel.Enable = 'off';
            app.NunberDropDownLabel.Text = 'Nunber';
            app.ViewPlotNumber = uidropdown(app.ViewPlotPropertiesPanel);
            app.ViewPlotNumber.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.ViewPlotNumber.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.ViewPlotNumber.Enable = 'off';
            app.ViewPlotNumber.Tooltip = {'maximum 9 plots'};
            app.ViewPlotNumber.Value = '1';
            app.HeightratioEditFieldLabel = uilabel(app.ViewPlotPropertiesPanel);
            app.HeightratioEditFieldLabel.Text = 'Height ratio';
            app.ViewPlotHeighRatio = uieditfield(app.ViewPlotPropertiesPanel, 'numeric');
            app.ViewPlotHeighRatio.Enable = 'off';
            app.ViewVariableSelectionPanel = uipanel(app.ViewOptionsTab);
            app.ViewVariableSelectionPanel.Title = 'Variable selections for plotting';
            app.ViewVariableGrid = uigridlayout(app.ViewVariableSelectionPanel);
            app.ViewVariable_1 = uidropdown(app.ViewVariableGrid);
            app.ViewVariable_1.Items = {};
            app.ViewVariable_1.Enable = 'off';
            app.ViewVariable_1.Tooltip = {'Select a variable to plot'};
            app.ViewVariable_1.Value = {};
            app.ViewVariable_2 = uidropdown(app.ViewVariableGrid);
            app.ViewVariable_2.Items = {};
            app.ViewVariable_2.Enable = 'off';
            app.ViewVariable_2.Tooltip = {'Select a variable to plot'};
            app.ViewVariable_2.Value = {};
            app.ViewVariable_3 = uidropdown(app.ViewVariableGrid);
            app.ViewVariable_3.Items = {};
            app.ViewVariable_3.Enable = 'off';
            app.ViewVariable_3.Tooltip = {'Select a variable to plot'};
            app.ViewVariable_3.Value = {};
            app.ViewVariable_4 = uidropdown(app.ViewVariableGrid);
            app.ViewVariable_4.Items = {};
            app.ViewVariable_4.Enable = 'off';
            app.ViewVariable_4.Tooltip = {'Select a variable to plot'};
            app.ViewVariable_4.Value = {};
            app.ViewVariable_5 = uidropdown(app.ViewVariableGrid);
            app.ViewVariable_5.Items = {};
            app.ViewVariable_5.Enable = 'off';
            app.ViewVariable_5.Tooltip = {'Select a variable to plot'};
            app.ViewVariable_5.Value = {};
            app.ViewVariable_6 = uidropdown(app.ViewVariableGrid);
            app.ViewVariable_6.Items = {};
            app.ViewVariable_6.Enable = 'off';
            app.ViewVariable_6.Tooltip = {'Select a variable to plot'};
            app.ViewVariable_6.Value = {};
            app.ViewVariable_7 = uidropdown(app.ViewVariableGrid);
            app.ViewVariable_7.Items = {};
            app.ViewVariable_7.Enable = 'off';
            app.ViewVariable_7.Tooltip = {'Select a variable to plot'};
            app.ViewVariable_7.Value = {};
            app.ViewVariable_8 = uidropdown(app.ViewVariableGrid);
            app.ViewVariable_8.Items = {};
            app.ViewVariable_8.Enable = 'off';
            app.ViewVariable_8.Tooltip = {'Select a variable to plot'};
            app.ViewVariable_8.Value = {};
            app.ViewVariable_9 = uidropdown(app.ViewVariableGrid);
            app.ViewVariable_9.Items = {};
            app.ViewVariable_9.Enable = 'off';
            app.ViewVariable_9.Tooltip = {'Select a variable to plot'};
            app.ViewVariable_9.Value = {};
            app.ViewVariablePropertiesPanel = uipanel(app.ViewOptionsTab);
            app.ViewVariablePropertiesPanel.Title = 'Variable properties';
            app.ViewVariablePropertiesGridTemplate = uigridlayout(app.ViewVariablePropertiesPanel);
            app.ViewXAxispropertiesPanel = uipanel(app.ViewOptionsTab);
            app.ViewXAxispropertiesPanel.Title = 'X-axis properties';
            app.VIewShowAllXAxis = uibutton(app.ViewXAxispropertiesPanel, 'state');
            app.VIewShowAllXAxis.Enable = 'off';
            app.VIewShowAllXAxis.Text = 'Show all X-axis';
            app.VIewShowAllXAxis.Value = true;
            app.XLimEditFieldLabel = uilabel(app.ViewXAxispropertiesPanel);
            app.XLimEditFieldLabel.Enable = 'off';
            app.XLimEditFieldLabel.Text = 'XLim';
            app.ViewXLim = uieditfield(app.ViewXAxispropertiesPanel, 'numeric');
            app.ViewXLim.Enable = 'off';
            app.ViewSaveOptionsPanel = uipanel(app.ViewOptionsTab);
            app.ViewSaveOptionsPanel.Title = 'Save options';
            app.ViewSaveSelectedOnly = uicheckbox(app.ViewSaveOptionsPanel);
            app.ViewSaveSelectedOnly.Enable = 'off';
            app.ViewSaveSelectedOnly.Text = 'selected only';
            app.ViewSaveDataFormat = uibuttongroup(app.ViewSaveOptionsPanel);
            app.ViewSaveMatButton = uiradiobutton(app.ViewSaveDataFormat);
            app.ViewSaveMatButton.Text = 'mat';
            app.ViewSaveMatButton.Value = true;
            app.ViewSaveCSVButton = uiradiobutton(app.ViewSaveDataFormat);
            app.ViewSaveCSVButton.Enable = 'off';
            app.ViewSaveCSVButton.Text = 'csv';
            app.ViewSaveDataButton = uibutton(app.ViewSaveOptionsPanel, 'push');
            app.ViewSaveDataButton.Enable = 'off';
            app.ViewSaveDataButton.Text = 'Save data';
            app.ViewScreenshotButton = uibutton(app.ViewSaveOptionsPanel, 'push');
            app.ViewScreenshotButton.Enable = 'off';
            app.ViewScreenshotButton.Text = 'Take screenshot';
            app.ViewLog = uitextarea(app.ViewOptionsTab);
            app.ViewLog.Enable = 'off';
            app.ViewLog.Value = {'Log'};
            app.AnalysisOptionsTab = uitab(app.OptionsGroup);
            app.AnalysisOptionsTab.Title = 'Analysis options';
            app.AnalysisLog = uitextarea(app.AnalysisOptionsTab);
            app.AnalysisLog.Enable = 'off';
            app.AnalysisLog.Value = {'Log'};
            app.OutputsPanel = uipanel(app.GridLayout);
            app.OutputsGroup = uitabgroup(app.OutputsPanel);
            app.ViewOutputsTab = uitab(app.OutputsGroup);
            app.ViewOutputsTab.Title = 'View output';
            app.ViewFigureBoundary = uipanel(app.ViewOutputsTab);
            app.AnalysisOutputsTab = uitab(app.OutputsGroup);
            app.AnalysisOutputsTab.Title = 'Analysis output';
            app.AnalysisFigureBoundary = uipanel(app.AnalysisOutputsTab);
            % Show the figure after all components are created
            app.MainFIgure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HEKA_Explorer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainFIgure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainFIgure)
        end
    end
end